classdef ArduMotor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Motor1TextAreaLabel   matlab.ui.control.Label
        Motor1TextArea        matlab.ui.control.TextArea
        PortLabel             matlab.ui.control.Label
        PortListBox           matlab.ui.control.ListBox
        refreshButton         matlab.ui.control.Button
        ConnectButton         matlab.ui.control.Button
        disconnectButton      matlab.ui.control.Button
        Motor2TextAreaLabel   matlab.ui.control.Label
        Motor2TextArea        matlab.ui.control.TextArea
        plusButton            matlab.ui.control.Button
        reduceButton          matlab.ui.control.Button
        RpmsettingLabel       matlab.ui.control.Label
        rpmlabel              matlab.ui.control.Label
        StopButton            matlab.ui.control.Button
        SetRpmEditFieldLabel  matlab.ui.control.Label
        SetRpmEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        connected=0; % Number of connected COM port
        s % Serial COM
        rpm_set = 2000;
        rpmdata = cell(1,2);
        CallingApp % Description
    end
    
    methods (Access = public)
        
        function readCOM(app)
            data = cell(1,app.connected);
            while app.connected > 0
                for i = 1: app.connected
                    port = app.s{i};
                    readData = fscanf(port);
                    data{i} = readData;
                end
                app.Motor1TextArea.Value = data{1};
            end
        end
        
        function readSerialData(app,src,~,i)
            data = readline(src);
            if i == 1 
                app.rpmdata{1} = [app.rpmdata{1},data];
                app.Motor1TextArea.Value = app.rpmdata{1};
                if length(app.rpmdata{1}) >10
                    app.rpmdata{1} = [];
                end
            elseif i == 2
                app.rpmdata{2} = [app.rpmdata{2},data];
                app.Motor2TextArea.Value = app.rpmdata{2};
                 if length(app.rpmdata{2}) >10
                    app.rpmdata{2} = [];
                end
            end
        end
        
        function wirte_rpm(app,rpm)
             for i = 1: app.connected
                  write(app.s{i}, num2str(rpm), 'char');
             end
             app.rpm_set = rpm;
             app.rpmlabel.Text = num2str(app.rpm_set);
        end
        
        function app = closeApp(app)
            app.UIFigureCloseRequest;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
        end

        % Button pushed function: refreshButton
        function refreshButtonPushed(app, event)
            port = serialportlist("available");
            app.PortListBox.Items = port;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.refreshButton.Enable = 0;
            items = app.PortListBox.Value;
            app.rpm_set = 2000;
            app.wirte_rpm(app.rpm_set);
            for i=1:length(items)
                app.s{i}=serialport(items{i},9600);
                configureCallback(app.s{i},"terminator",@(src,eventdata)readSerialData(app,src,eventdata,i));
                app.connected = app.connected +1;
            end             
            app.ConnectButton.Visible = 0;
            app.disconnectButton.Visible = 1;
        end

        % Button pushed function: disconnectButton
        function disconnectButtonPushed(app, event)
%              for i=1:length(app.PortListBox.Items)
%                  fclose(s(i));
%                  delete(s(i));
%                  app.connected = app.connected - 1;
%              end
            delete(instrfindall);
            app.s =[];
            app.connected = 0;
            app.refreshButton.Enable=1;
            app.ConnectButton.Visible = 1;
            app.disconnectButton.Visible = 0;
        end

        % Button pushed function: plusButton
        function plusButtonPushed(app, event)
            app.wirte_rpm(app.rpm_set+500);
        end

        % Button pushed function: reduceButton
        function reduceButtonPushed(app, event)
            app.wirte_rpm(app.rpm_set-500);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.wirte_rpm(0);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.wirte_rpm(0)
            app.disconnectButtonPushed()
            app.CallingApp.RpmControlButton.Value = 0;
            delete(app)
            
        end

        % Value changed function: SetRpmEditField
        function SetRpmEditFieldValueChanged(app, event)
            value = app.SetRpmEditField.Value;
            app.rpm_set = value;
            app.wirte_rpm(app.rpm_set);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Motor1TextAreaLabel = uilabel(app.UIFigure);
            app.Motor1TextAreaLabel.Text = 'Motor1';
            app.Motor1TextArea = uitextarea(app.UIFigure);
            app.PortLabel = uilabel(app.UIFigure);
            app.PortLabel.Text = 'Port:';
            app.PortListBox = uilistbox(app.UIFigure);
            app.PortListBox.Items = {};
            app.PortListBox.Multiselect = 'on';
            app.PortListBox.Value = {};
            app.refreshButton = uibutton(app.UIFigure, 'push');
            app.refreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshButtonPushed, true);
            app.refreshButton.Text = 'refresh';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.disconnectButton = uibutton(app.UIFigure, 'push');
            app.disconnectButton.ButtonPushedFcn = createCallbackFcn(app, @disconnectButtonPushed, true);
            app.disconnectButton.Visible = 'off';
            app.disconnectButton.Text = 'disconnect';
            app.Motor2TextAreaLabel = uilabel(app.UIFigure);
            app.Motor2TextAreaLabel.Text = 'Motor2';
            app.Motor2TextArea = uitextarea(app.UIFigure);
            app.plusButton = uibutton(app.UIFigure, 'push');
            app.plusButton.ButtonPushedFcn = createCallbackFcn(app, @plusButtonPushed, true);
            app.plusButton.Text = '+500';
            app.reduceButton = uibutton(app.UIFigure, 'push');
            app.reduceButton.ButtonPushedFcn = createCallbackFcn(app, @reduceButtonPushed, true);
            app.reduceButton.Text = '-500';
            app.RpmsettingLabel = uilabel(app.UIFigure);
            app.RpmsettingLabel.Text = 'Rpm setting:';
            app.rpmlabel = uilabel(app.UIFigure);
            app.rpmlabel.Text = '2000';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SetRpmEditFieldLabel = uilabel(app.UIFigure);
            app.SetRpmEditFieldLabel.Text = 'Set Rpm';
            app.SetRpmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetRpmEditField.ValueChangedFcn = createCallbackFcn(app, @SetRpmEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArduMotor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end