classdef DaqSet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DaqDevicesListBoxLabel   matlab.ui.control.Label
        DaqDevicesListBox        matlab.ui.control.ListBox
        Refresh                  matlab.ui.control.Button
        ChannlesSpinnerLabel     matlab.ui.control.Label
        ChannlesSpinner          matlab.ui.control.Spinner
        TypeDropDownLabel        matlab.ui.control.Label
        TypeDropDown             matlab.ui.control.DropDown
        StartsfromSpinnerLabel   matlab.ui.control.Label
        StartsfromSpinner        matlab.ui.control.Spinner
        FrequencyEditFieldLabel  matlab.ui.control.Label
        FrequencyEditField       matlab.ui.control.NumericEditField
        ConfigDropDownLabel      matlab.ui.control.Label
        ConfigDropDown           matlab.ui.control.DropDown
        ConnectButton            matlab.ui.control.Button
        RangeEditFieldLabel      matlab.ui.control.Label
        RangeEditField           matlab.ui.control.NumericEditField
        EditFieldLabel           matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    
    methods (Access = public)
        
        function app = closeApp(app)
            app.UIFigureCloseRequest;  
        end
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
        end

        % Button pushed function: Refresh
        function RefreshButtonPushed(app, event)
            d = daqlist("ni");
            devices = d{:,"DeviceID"};
            app.DaqDevicesListBox.Items = devices;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            dvc = app.DaqDevicesListBox.Value;
            ch_num = app.ChannlesSpinner.Value;
            Frequency = app.FrequencyEditField.Value;
            ch0 = app.StartsfromSpinner.Value;
            range = [app.RangeEditField.Value, app.EditField.Value];
            config = app.ConfigDropDown.Value;
            type = app.TypeDropDown.Value;
            app.CallingApp.setDaq(ch_num,Frequency,ch0,range,config,dvc,type)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.DaqSettingButton.Value = 0;
            delete(app)    
        end

        % Value changed function: DaqDevicesListBox
        function DaqDevicesListBoxValueChanged(app, event)
            value = app.DaqDevicesListBox.Value;
            if value
                app.ConnectButton.Enable = 1;
            else
                app.ConnectButton.Enable = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DaqDevicesListBoxLabel = uilabel(app.UIFigure);
            app.DaqDevicesListBoxLabel.Text = 'Daq Devices';
            app.DaqDevicesListBox = uilistbox(app.UIFigure);
            app.DaqDevicesListBox.Items = {};
            app.DaqDevicesListBox.ValueChangedFcn = createCallbackFcn(app, @DaqDevicesListBoxValueChanged, true);
            app.DaqDevicesListBox.Value = {};
            app.Refresh = uibutton(app.UIFigure, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.Refresh.Icon = 'refresh.png';
            app.Refresh.Text = '';
            app.ChannlesSpinnerLabel = uilabel(app.UIFigure);
            app.ChannlesSpinnerLabel.Text = 'Channles';
            app.ChannlesSpinner = uispinner(app.UIFigure);
            app.ChannlesSpinner.Limits = [1 48];
            app.ChannlesSpinner.Value = 7;
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'Voltage', 'Thermocouple', 'Current', 'Accelerometer', 'RTD', 'Bridge', 'Microphone', 'IEPE'};
            app.TypeDropDown.Value = 'Voltage';
            app.StartsfromSpinnerLabel = uilabel(app.UIFigure);
            app.StartsfromSpinnerLabel.Text = 'Starts from';
            app.StartsfromSpinner = uispinner(app.UIFigure);
            app.StartsfromSpinner.Limits = [0 47];
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Limits = [0 Inf];
            app.FrequencyEditField.Value = 5000;
            app.ConfigDropDownLabel = uilabel(app.UIFigure);
            app.ConfigDropDownLabel.Text = 'Config';
            app.ConfigDropDown = uidropdown(app.UIFigure);
            app.ConfigDropDown.Items = {'SingleEnded', '', 'SingleEndedNonReferenced', '', 'Differential', '', 'PseudoDifferential'};
            app.ConfigDropDown.Value = 'SingleEnded';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Text = 'Connect';
            app.RangeEditFieldLabel = uilabel(app.UIFigure);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RangeEditField.Value = -10;
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '-';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DaqSet(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end