classdef app1_beta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        DaqSettingButton         matlab.ui.control.StateButton
        DisconnectButton         matlab.ui.control.Button
        RpmControlButton         matlab.ui.control.StateButton
        ReversedDirCheckBox      matlab.ui.control.CheckBox
        OutputGauge              matlab.ui.control.LinearGauge
        OutputGaugeLabel         matlab.ui.control.Label
        ResetButton              matlab.ui.control.Button
        Ardu_label               matlab.ui.control.Label
        Ardu_lamp                matlab.ui.control.Lamp
        PinDropDown              matlab.ui.control.DropDown
        PinDropDownLabel         matlab.ui.control.Label
        Lamp_cali                matlab.ui.control.Lamp
        CaliButton               matlab.ui.control.Button
        TimesLabel               matlab.ui.control.Label
        timer                    matlab.ui.control.Label
        SaveDataButton           matlab.ui.control.Button
        ConnectButton            matlab.ui.control.Button
        Refresh                  matlab.ui.control.Button
        PortDropDown             matlab.ui.control.DropDown
        PortDropDownLabel        matlab.ui.control.Label
        ClearButton              matlab.ui.control.Button
        KdEditField              matlab.ui.control.NumericEditField
        KdEditFieldLabel         matlab.ui.control.Label
        KiEditField              matlab.ui.control.NumericEditField
        KiEditFieldLabel         matlab.ui.control.Label
        KpEditField              matlab.ui.control.NumericEditField
        KpEditFieldLabel         matlab.ui.control.Label
        Switch                   matlab.ui.control.ToggleSwitch
        Lamp                     matlab.ui.control.Lamp
        Label                    matlab.ui.control.Label
        EndButton                matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        SetForceNEditField       matlab.ui.control.NumericEditField
        SetForceNEditFieldLabel  matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        UIAxes2_2                matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
        FileMenu                 matlab.ui.container.Menu
        LoadConfigMenu           matlab.ui.container.Menu
        SaveConfigMenu           matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Md = MotorDaq % Description
        RpmCtr
        DaqS
    end
    
    methods (Access = private)
        
        function clearPlot(app)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes2,'reset');

            title(app.UIAxes, 'Force')
            xlabel(app.UIAxes, 'time(s)')
            ylabel(app.UIAxes, 'Fx')
            app.UIAxes.Position = [33 403 319 210];
            axis(app.UIAxes,[0,50,-1.5,1.5]);

            title(app.UIAxes2, 'Current')
            xlabel(app.UIAxes2, 'time(s)')
            ylabel(app.UIAxes2, 'I')
            app.UIAxes2.Position = [33 199 319 194];
            axis(app.UIAxes2,[0,50,0,20]);


            title(app.UIAxes2_2, 'Speed')
            xlabel(app.UIAxes2_2, 'time(s)')
            ylabel(app.UIAxes2_2, 'rpm')
            app.UIAxes2_2.Position = [33 6 319 194];

            
        end
        
        function saveConfig(app)
            [filename,save_path] = uiputfile('.log','Saving Config','config');
            if length(filename) < 2 || length(save_path) < 2
                return;
            end
            fid = fopen([save_path,filename],'w');
            fprintf(fid,'%.5f\r\n',app.SetForceNEditField.Value);
            fprintf(fid,'%.5f\r\n',app.KpEditField.Value);
            fprintf(fid,'%.5f\r\n',app.KiEditField.Value);
            fprintf(fid,'%.5f\r\n',app.KdEditField.Value);
            fclose(fid);
    
            
        end
        
        function app = setConfig(app,set)
            app.SetForceNEditField.Value = set(1);
            app.KpEditField.Value = set(2);
            app.KiEditField.Value = set(3);
            app.KdEditField.Value = set(4);
            app.Md.setTarget(set(1));
            app.Md.P.setKp(set(2));
            app.Md.P.setKi(set(3));
            app.Md.P.setKd(set(4));
            
        end
        
        function app = lockdaq(app)
            app.StartButton.Enable = 'off';
            app.StartButton.Visible = 'off';
            app.EndButton.Enable = 'on';
            app.EndButton.Visible = 'on';
            app.CaliButton.Enable = 'off';
            app.SaveDataButton.Enable = 'off'; 
            app.DaqSettingButton.Enable = 'off';
        end
        
        function app = unlockdaq(app)
            app.StartButton.Enable = 'on';
            app.StartButton.Visible = 'on';
            app.EndButton.Enable = 'off';
            app.EndButton.Visible = 'off';
            app.CaliButton.Enable = 'on';
            app.SaveDataButton.Enable = 'on';
            app.DaqSettingButton.Enable = 'on';
        end
    end
    
    
    methods (Access = public)
        
        function app = setDaq(app,ch_num,Frequency,ch0,range,config,device,type)
            app.Md.setDaq(ch_num,Frequency,ch0,range,config,device,type);
            app.unlockdaq;
            app.DaqSettingButton.BackgroundColor = 'green';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, log)
            app.Md.setRealTimeFig({app.UIAxes,app.UIAxes2});
            app.Md.openApp(app);
            app.DaqS = DaqSet(app);
            app.DaqSettingButton.Value = 1;
            
            app.StartButton.Enable = 'off';
            app.EndButton.Enable = 'off';
            app.CaliButton.Enable = 'off';
            app.SaveDataButton.Enable = 'off';
        end

        % Value changed function: SetForceNEditField
        function SetForceNEditFieldValueChanged(app, event)
            value = app.SetForceNEditField.Value;
            app.Md.setTarget(value);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.Lamp.Color = 'green';
            app.Md.setRealTimeFig({app.UIAxes,app.UIAxes2});
            app.Md.openApp(app);
            app.StartButton.Enable = 'off';
            app.StartButton.Visible = 'off';
            app.EndButton.Enable = 'on';
            app.EndButton.Visible = 'on';
            app.CaliButton.Enable = 'off';
            app.SaveDataButton.Enable = 'off';
            if app.Switch.Value == 'Daq&PID'
                app.Md.startMotorControl();
            else
                app.Md.startDaq();
            end

            %app.clearPlot;
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            app.Md.endMotorControl();
            app.Lamp.Color = 'red';
            app.unlockdaq;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.Md.dataReset();
            app.timer.Text = '0.0';
            app.clearPlot;
        end

        % Value changed function: KpEditField
        function KpEditFieldValueChanged(app, event)
            value = app.KpEditField.Value;
            app.Md.P.setKp(value);
        end

        % Value changed function: KiEditField
        function KiEditFieldValueChanged(app, event)
            value = app.KiEditField.Value;
            app.Md.P.setKi(value);
        end

        % Value changed function: KdEditField
        function KdEditFieldValueChanged(app, event)
            value = app.KdEditField.Value;
            app.Md.P.setKd(value);
        end

        % Button pushed function: Refresh
        function RefreshPushed(app, event)
            port = serialportlist("available");
            app.PortDropDown.Items = port;
        end

        % Value changed function: PortDropDown
        function PortDropDownValueChanged(app, event)
            value = app.PortDropDown.Value;
            %app.Md.arduConnect(value);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            %app.Md.arduClear();
            value = app.PortDropDown.Value;
            pin = app.PinDropDown.Value;
            app.Ardu_label.Text = "Connecting...";
            app.Ardu_lamp.Color = "Yellow";
            try
                app.Md.arduConnect(value,pin);
%                 app.Md.arduConnect_com(value);
                app.Ardu_label.Text = "Connected";
                app.Ardu_lamp.Color = "Green";
                app.ConnectButton.Enable = "off";
                app.ConnectButton.Visible = "off";
                app.DisconnectButton.Enable = "on";
                app.DisconnectButton.Visible = "on";
                
            catch ME
                app.Ardu_lamp.Color = "Red";
                app.Ardu_label.Text = ['Connect failed:',ME.identifier];
            end
                
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {623, 623};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {286, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            app.Md.saveData();
        end

        % Button pushed function: CaliButton
        function CaliButtonPushed(app, event)
            app.Lamp_cali.Color = 'yellow';
            app.Md.setRealTimeFig({app.UIAxes,app.UIAxes2});
            app.Md.openApp(app);

            app.lockdaq;
            
            app.Md.calibration();
            app.unlockdaq;
            app.Lamp_cali.Color = 'green';
            app.Md.endMotorControl();
        end

        % Menu selected function: LoadConfigMenu
        function LoadConfigMenuSelected(app, event)
            [file,path] = uigetfile( {'*.log'},'.\','Choose config file:' );
            if length(file) < 2 || length(path) < 2
                return;
            end
            set = importdata([path,file]);
            app.setConfig(set);

        end

        % Menu selected function: SaveConfigMenu
        function SaveConfigMenuSelected(app, event)
            app.saveConfig();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Md.P.clear_PID()
        end

        % Value changed function: ReversedDirCheckBox
        function ReversedDirCheckBoxValueChanged(app, event)
            value = app.ReversedDirCheckBox.Value;
            app.Md.changeDir();
        end

        % Value changed function: RpmControlButton
        function RpmControlButtonValueChanged(app, event)
            value = app.RpmControlButton.Value;
            if value
                app.RpmCtr = ArduMotor(app);
            else
                app.RpmCtr.closeApp;
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.Md.arduClear();
            app.Ardu_lamp.Color = [0.651 0.651 0.651];
            app.RefreshPushed;
            app.ConnectButton.Enable = "on";
            app.ConnectButton.Visible = "on";
            app.DisconnectButton.Enable = "off";
            app.DisconnectButton.Visible = "off";
        end

        % Value changed function: DaqSettingButton
        function DaqSettingButtonValueChanged(app, event)
            value = app.DaqSettingButton.Value;
            if value
                app.DaqS = DaqSet(app);
            else
                app.DaqS.closeApp;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SetForceNEditFieldLabel = uilabel(app.LeftPanel);
            app.SetForceNEditFieldLabel.Text = 'Set Force (N)';
            app.SetForceNEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SetForceNEditField.ValueChangedFcn = createCallbackFcn(app, @SetForceNEditFieldValueChanged, true);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EndButton = uibutton(app.LeftPanel, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Enable = 'off';
            app.EndButton.Visible = 'off';
            app.EndButton.Text = 'End';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.Lamp = uilamp(app.LeftPanel);
            app.Switch = uiswitch(app.LeftPanel, 'toggle');
            app.Switch.Items = {'DaqOnly', 'Daq&PID'};
            app.Switch.Value = 'DaqOnly';
            app.KpEditFieldLabel = uilabel(app.LeftPanel);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.LeftPanel, 'numeric');
            app.KpEditField.ValueChangedFcn = createCallbackFcn(app, @KpEditFieldValueChanged, true);
            app.KiEditFieldLabel = uilabel(app.LeftPanel);
            app.KiEditFieldLabel.Text = {'Ki'; ''};
            app.KiEditField = uieditfield(app.LeftPanel, 'numeric');
            app.KiEditField.ValueChangedFcn = createCallbackFcn(app, @KiEditFieldValueChanged, true);
            app.KdEditFieldLabel = uilabel(app.LeftPanel);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.LeftPanel, 'numeric');
            app.KdEditField.ValueChangedFcn = createCallbackFcn(app, @KdEditFieldValueChanged, true);
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.PortDropDownLabel = uilabel(app.LeftPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.LeftPanel);
            app.PortDropDown.Items = {};
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownValueChanged, true);
            app.PortDropDown.Value = {};
            app.Refresh = uibutton(app.LeftPanel, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshPushed, true);
            app.Refresh.Icon = 'refresh.png';
            app.Refresh.Text = '';
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.SaveDataButton = uibutton(app.LeftPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.timer = uilabel(app.LeftPanel);
            app.timer.Text = '0.0';
            app.TimesLabel = uilabel(app.LeftPanel);
            app.TimesLabel.Text = 'Time(s):';
            app.CaliButton = uibutton(app.LeftPanel, 'push');
            app.CaliButton.ButtonPushedFcn = createCallbackFcn(app, @CaliButtonPushed, true);
            app.CaliButton.Text = 'Cali';
            app.Lamp_cali = uilamp(app.LeftPanel);
            app.PinDropDownLabel = uilabel(app.LeftPanel);
            app.PinDropDownLabel.Text = 'Pin';
            app.PinDropDown = uidropdown(app.LeftPanel);
            app.PinDropDown.Items = {'D1', 'D2', 'D3', 'D4', 'D5'};
            app.PinDropDown.Value = 'D5';
            app.Ardu_lamp = uilamp(app.LeftPanel);
            app.Ardu_label = uilabel(app.LeftPanel);
            app.Ardu_label.Text = '';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.OutputGaugeLabel = uilabel(app.LeftPanel);
            app.OutputGaugeLabel.Text = 'Output';
            app.OutputGauge = uigauge(app.LeftPanel, 'linear');
            app.OutputGauge.Limits = [800 2300];
            app.ReversedDirCheckBox = uicheckbox(app.LeftPanel);
            app.ReversedDirCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReversedDirCheckBoxValueChanged, true);
            app.ReversedDirCheckBox.Text = 'Reversed Dir';
            app.RpmControlButton = uibutton(app.LeftPanel, 'state');
            app.RpmControlButton.ValueChangedFcn = createCallbackFcn(app, @RpmControlButtonValueChanged, true);
            app.RpmControlButton.Text = 'Rpm Control';
            app.DisconnectButton = uibutton(app.LeftPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Visible = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.DaqSettingButton = uibutton(app.LeftPanel, 'state');
            app.DaqSettingButton.ValueChangedFcn = createCallbackFcn(app, @DaqSettingButtonValueChanged, true);
            app.DaqSettingButton.Text = {'Daq'; 'Setting'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Force')
            xlabel(app.UIAxes, 'time(s)')
            ylabel(app.UIAxes, 'Fx')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Current')
            xlabel(app.UIAxes2, 'time(s)')
            ylabel(app.UIAxes2, 'I')
            app.UIAxes2_2 = uiaxes(app.RightPanel);
            title(app.UIAxes2_2, 'Speed')
            xlabel(app.UIAxes2_2, 'time(s)')
            ylabel(app.UIAxes2_2, 'rpm')
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadConfigMenu = uimenu(app.FileMenu);
            app.LoadConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadConfigMenuSelected, true);
            app.LoadConfigMenu.Separator = 'on';
            app.LoadConfigMenu.Accelerator = 'q';
            app.LoadConfigMenu.Text = 'Load Config';
            app.SaveConfigMenu = uimenu(app.FileMenu);
            app.SaveConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveConfigMenuSelected, true);
            app.SaveConfigMenu.Separator = 'on';
            app.SaveConfigMenu.Accelerator = 'e';
            app.SaveConfigMenu.Text = 'Save Config';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_beta(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end